classdef perceive_gui_startup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ToolboxLabel          matlab.ui.control.Label
        SelectedFilesLabel    matlab.ui.control.Label
        processfilesButton    matlab.ui.control.Button
        outputDir             matlab.ui.control.EditField
        outputdirectoryLabel  matlab.ui.control.Label
        FilePickerValue       matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        SelectedFilePaths % Stores the selected file paths
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the stored file paths
            app.SelectedFilePaths = {};
            app.SelectedFilesLabel.Text = 'Click "Browse JSON Files" to select files.';
        end

        % Button pushed function: FilePickerValue
        function FilePickerValuePushed(app, event)
            % Open a file selection dialog for .json files with multi-select enabled
            [filenames, pathname] = uigetfile( ...
                {'*.json', 'JSON Files (*.json)'}, ...
                'Select one or more JSON files', ...
                'MultiSelect', 'on');

            % Handle case where user cancels the dialog
            if isequal(filenames, 0)
                app.SelectedFilesLabel.Text = 'No file selected.';
                % Reset the stored file paths
                app.SelectedFilePaths = {};
            else
                % Combine path and filenames into full paths
                if iscell(filenames)
                    app.SelectedFilePaths = cellfun(@(f) fullfile(pathname, f), filenames, 'UniformOutput', false);
                else
                    app.SelectedFilePaths = {fullfile(pathname, filenames)};
                end

                % Display the selected files in the label
                app.SelectedFilesLabel.Text = sprintf('Selected files (%d):\n%s', ...
                    length(app.SelectedFilePaths), ...
                    strjoin(app.SelectedFilePaths, '\n'));
            end
        end

        % Button pushed function: processfilesButton
        function processfilesButtonPushed(app, event)
            if isempty(app.SelectedFilePaths)
                app.SelectedFilesLabel.Text = 'No files selected for processing.';
                % Call perceive() to process all files in the current directory
                perceive();
            elseif length(app.SelectedFilePaths) == 1
                % Call perceive with the single file
                perceive(app.SelectedFilePaths{1});
            else
                % Call perceive with the list of files
                perceive(app.SelectedFilePaths);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.FilePickerValue = uibutton(app.UIFigure, 'push');
            app.FilePickerValue.ButtonPushedFcn = createCallbackFcn(app, @FilePickerValuePushed, true);
            app.FilePickerValue.Text = 'Browse';
            app.outputdirectoryLabel = uilabel(app.UIFigure);
            app.outputdirectoryLabel.Text = 'json files:';
            app.outputDir = uieditfield(app.UIFigure, 'text');
            app.processfilesButton = uibutton(app.UIFigure, 'push');
            app.processfilesButton.ButtonPushedFcn = createCallbackFcn(app, @processfilesButtonPushed, true);
            app.processfilesButton.Text = 'process files';
            app.SelectedFilesLabel = uilabel(app.UIFigure);
            app.SelectedFilesLabel.Text = 'no file selected';
            app.ToolboxLabel = uilabel(app.UIFigure);
            app.ToolboxLabel.Text = 'Perceive startup';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perceive_gui_startup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end