classdef update_tasks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        runDropDown            matlab.ui.control.DropDown
        runDropDownLabel       matlab.ui.control.Label
        redoButton             matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        filenameLabel          matlab.ui.control.Label
        saveandcontinueButton  matlab.ui.control.Button
        taskDropDown           matlab.ui.control.DropDown
        taskDropDownLabel      matlab.ui.control.Label
    end

    properties (Access = public)
        MetaT
    end
    properties (Access = private)
       
        numberoffiles=0% Description
        iterator=1;
        
    end

    methods
        function nextrow(app)
            app.filenameLabel.Text = app.MetaT.perceiveFilename(app.iterator);
            app.taskDropDown.Value = app.MetaT.task{app.iterator};
            app.runDropDown.Value = app.MetaT.run{app.iterator};
            removeStyle(app.UITable)
            s = uistyle("BackgroundColor",[1 0.6 0.6]);
            row = [app.iterator;app.iterator;app.iterator];
            col = [2;5;7];
            addStyle(app.UITable,s,"cell",[row,col]);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MetaT)
            app.MetaT = MetaT;
            app.UITable.Data = app.MetaT;
            app.UITable.ColumnWidth = 'fit';
            app.numberoffiles=length(app.MetaT.perceiveFilename);
            nextrow(app);
        end

        % Button pushed function: saveandcontinueButton
        function saveandcontinueButtonPushed(app, event)
            app.MetaT.task(app.iterator) = {app.taskDropDown.Value};
            app.MetaT.run(app.iterator) = {app.runDropDown.Value};
            app.UITable.Data = app.MetaT;
            
            
            if app.iterator == app.numberoffiles
                for i = 1:app.numberoffiles
                    
                    task=regexp(app.MetaT.perceiveFilename{i},'(?<=task-).*(?=_acq)','match');
                    app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},task{1},app.MetaT.task{i});
                    run=regexp(app.MetaT.perceiveFilename{i},'(?<=run-).*(?=.mat)','match');
                    app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},run{1},app.MetaT.run{i});
                end
                if length(unique(app.MetaT.perceiveFilename)) == length(app.MetaT.perceiveFilename)
                    app.saveandcontinueButton.UserData=1;
                else
                    errordlg('Files have not unique names. Please adapt task and run numbers','File Error');
                    app.iterator = 1;
                    app.UITable.Data = app.MetaT;
                    nextrow(app);
                    app.saveandcontinueButton.Text = 'Save and continue';
                end
            else
                app.iterator = app.iterator + 1;
                nextrow(app);
           
                if app.iterator == app.numberoffiles
                    app.saveandcontinueButton.Text = 'Evaluate';
                else
                    app.saveandcontinueButton.Text = 'Save and continue';
                end
            end

        end

        % Button pushed function: redoButton
        function redoButtonPushed(app, event)
            app.iterator = 1;
            nextrow(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.taskDropDownLabel = uilabel(app.UIFigure);
            app.taskDropDownLabel.Text = 'task';
            app.taskDropDown = uidropdown(app.UIFigure);
            app.taskDropDown.Items = {'Rest', 'RestTap', 'FingerTap', 'FingerTapL', 'FingerTapR', 'Rota', 'UPDRS', 'MovArtWalk', 'MovArtStand', 'MovArtHead', 'MovArtArms', 'FTGRampUpThres', 'FTGFastRamp125Hz', 'FTG110Hz', 'FTGBilateral', 'FTG145Hz', 'MID', 'SMTS', 'MSST', 'TASK1', 'TASK2', 'TASK3', 'TASK4', 'TASK5', 'TASK6', 'TASK7', 'TASK8', 'TASK9'};
            app.taskDropDown.Value = 'Rest';
            app.saveandcontinueButton = uibutton(app.UIFigure, 'push');
            app.saveandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @saveandcontinueButtonPushed, true);
            app.saveandcontinueButton.Text = 'save and continue';
            app.filenameLabel = uilabel(app.UIFigure);
            app.filenameLabel.Text = 'filename';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'''report'''; '''perceiveFilename'''; '''session'''; '''condition'''; '''task'''; '''contacts'''; '''run'''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.UITable.Enable = 'off';
            app.redoButton = uibutton(app.UIFigure, 'push');
            app.redoButton.ButtonPushedFcn = createCallbackFcn(app, @redoButtonPushed, true);
            app.redoButton.Text = 'redo';
            app.runDropDownLabel = uilabel(app.UIFigure);
            app.runDropDownLabel.Text = 'run';
            app.runDropDown = uidropdown(app.UIFigure);
            app.runDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.runDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = update_tasks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end